classdef real_time_fall_detection_by_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        YourpathienthasfaleenLabel  matlab.ui.control.Label
        ErrorlabelLabel             matlab.ui.control.Label
        TimeLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ThresholdDropDown           matlab.ui.control.DropDown
        ThresholdDropDownLabel      matlab.ui.control.Label
        DisconnectedLabel           matlab.ui.control.Label
        ConnectionStatusLamp        matlab.ui.control.Lamp
        ConnectionStatusLampLabel   matlab.ui.control.Label
        eg19216817621Label          matlab.ui.control.Label
        EnterIPAddressEditField     matlab.ui.control.EditField
        httpIP_AdresseLabel         matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        ClearAllButton              matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        Label_2                     matlab.ui.control.Label
        UnknownLabel                matlab.ui.control.Label
        LastFallTimeLabel           matlab.ui.control.Label
        FallStatusLabel             matlab.ui.control.Label
        NormalLabel                 matlab.ui.control.Label
        UIAxesAccX                  matlab.ui.control.UIAxes
        UIAxesAccY                  matlab.ui.control.UIAxes
        UIAxesAccZ                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        ipAdress=[]; % ipAdresse von PhyPhox
        startSting='control?cmd=start';
        stopSting='control?cmd=stop';
        clearString='control?cmd=clear';
        pollAccelX='accX';
        pollAccelY='accY';
        pollAccelZ='accZ';
        pollAccelTime='acc_time';
        pollAll='=full';
        pollString='get?';
        options=weboptions('ContentType','json');
        record=0;

        figAccelX=[];
        figAccelY=[];
        figAccelZ=[];

        accelXData=[];
        accelYData=[];
        accelZData=[];
        accelTime=[];
        fallCount = 0;  % Counter for the number of falls detected
        % User-defined properties
        thresholdValue = 10; % Default threshold value


    end


    methods (Access = private)


        function [] = plotFunktion(app)
            % Placeholder for any additional plotting logic
        end

        function fall_segments = findFallSegments(app, falls, min_duration, sample_rate)
            fall_segments = {};
            current_segment = [];
            for i = 1:length(falls)
                if falls(i)
                    current_segment = [current_segment i];
                else
                    if length(current_segment) / sample_rate >= min_duration
                        fall_segments{end+1} = current_segment; %#ok<AGROW>
                    end
                    current_segment = [];
                end
            end
            % Check the last segment
            if length(current_segment) / sample_rate >= min_duration
                fall_segments{end+1} = current_segment; %#ok<AGROW>
            end
            % Debug: Print fall segments
            % disp('findFallSegments output:');
            % disp(fall_segments);

        end

        function [] = pollData(app)
            % Define the moving average filter
            windowSize = 5;
            b = (1/windowSize) * ones(1, windowSize);
            a = 1;

            % Initialize fall detection state
            fall_detected = false;

            % Initialize plot handles
            app.figAccelX = plot(app.UIAxesAccX, app.accelTime, app.accelXData);
            hold(app.UIAxesAccX, 'on');
            app.figAccelY = plot(app.UIAxesAccY, app.accelTime, app.accelYData);
            hold(app.UIAxesAccY, 'on');
            app.figAccelZ = plot(app.UIAxesAccZ, app.accelTime, app.accelZData);
            hold(app.UIAxesAccZ, 'on');
            fallMarkerX = plot(app.UIAxesAccX, NaN, NaN, 'ro');
            fallMarkerY = plot(app.UIAxesAccY, NaN, NaN, 'ro');
            fallMarkerZ = plot(app.UIAxesAccZ, NaN, NaN, 'ro');
            hold(app.UIAxesAccX, 'off');
            hold(app.UIAxesAccY, 'off');
            hold(app.UIAxesAccZ, 'off');

            while app.record == 1
                data = webread([app.ipAdress app.pollString '&' app.pollAccelX '&' app.pollAccelY '&' app.pollAccelZ '&' app.pollAccelTime], app.options);

                % Update acceleration data
                app.accelXData = [app.accelXData data.buffer.accX.buffer];
                app.accelYData = [app.accelYData data.buffer.accY.buffer];
                app.accelZData = [app.accelZData data.buffer.accZ.buffer];
                app.accelTime = [app.accelTime data.buffer.acc_time.buffer];

                % Apply the moving average filter
                filteredAccelX = filter(b, a, app.accelXData);
                filteredAccelY = filter(b, a, app.accelYData);
                filteredAccelZ = filter(b, a, app.accelZData);

                % Fall detection logic
                acc_mag = sqrt(filteredAccelX.^2 + filteredAccelY.^2 + filteredAccelZ.^2);
                total_force= acc_mag/9.8;


                fall_threshold = app.thresholdValue; % User-defined threshold value
                min_fall_duration = 0.5; % Minimum duration for a fall (seconds)
                sample_rate = 100; % Sample rate of the accelerometer data

                % Find falls based on threshold
                falls = acc_mag > fall_threshold;
                % Debug: Display total_force values
                % disp(total_force);

                % Initialize status as no fall
                falling_status = '---';
disp(app.thresholdValue);

                % Check if any value in total_force exceeds the threshold
                if any(falls(end) >= 1) && ~fall_detected % Adjust threshold as needed
                    % Fall detected
                    fall_detected = true;
                    falling_status = 'Fall Detected';
                    app.fallCount = app.fallCount + 1;  % Increment fall count

                    % design
                    color = 'red';

                    % beep sound
                    beep on; % Enable beeping if it's disabled
                    fs = 44100; % Sampling frequency
                    duration = 0.1; % Duration of the beep in seconds
                    frequency = 1000; % Frequency of the beep in Hz
                    t = 0:1/fs:duration; % Time vector
                    y = sin(2*pi*frequency*t); % Generate a sine wave
                    sound(y, fs); % Play the sound
                    disp('if fall detected');
                    disp(datetime("now"));
                    currentTime = datestr(datetime("now"));
                    app.UnknownLabel.Text = currentTime;
                    app.Label_2.Text = 'Your tracking user Is Fallen Please verify and Take necessary actions'

                elseif ~any(falls(end) >= 1)
                    % No fall detected, reset fall_detected state
                    fall_detected = false;
                    falling_status = 'Normal';
                    color = [0.01,0.53,0.01];

                    % else
                    %     % If a fall was previously detected, reset the state
                    %     fall_detected = false;
                    %     falling_status = '---';
                    %     color = 'black';
                    %         disp('else');
                end
                % disp(falls);
                % disp(falls(start));

                % Update the status label
                app.NormalLabel.FontColor= color;  % Set color to red
                app.NormalLabel.Text = falling_status;
                app.Label.FontColor = 'red';
                app.Label.Text = num2str(app.fallCount);  % fall count

                % Debug: Display which condition was met
                if strcmp(falling_status, 'Falling')
                    disp('Falling detected');

                    % disp('fallcount: '+ num2str(app.fallCount));
                    beep;
                else
                    disp('No fall detected');
                end


                % dfall debug
                % disp(falls(end));
                % disp(total_force);
                % disp("total force last");
                % disp(max(total_force));
                % disp('threshold value');
                % disp(fall_threshold);

                %
                % % Update plots
                % set(app.figAccelX, 'XData', app.accelTime, 'YData', app.accelXData);
                % set(app.figAccelY, 'XData', app.accelTime, 'YData', app.accelYData);
                % set(app.figAccelZ, 'XData', app.accelTime, 'YData', app.accelZData);

                % Update plots
                if isvalid(app.figAccelX)
                    set(app.figAccelX, 'XData', app.accelTime, 'YData', app.accelXData);
                end
                if isvalid(app.figAccelY)
                    set(app.figAccelY, 'XData', app.accelTime, 'YData', app.accelYData);
                end
                if isvalid(app.figAccelZ)
                    set(app.figAccelZ, 'XData', app.accelTime, 'YData', app.accelZData);
                end

                % Clear previous fall markers
                if isvalid(fallMarkerX)
                    set(fallMarkerX, 'XData', NaN, 'YData', NaN);
                end
                if isvalid(fallMarkerY)
                    set(fallMarkerY, 'XData', NaN, 'YData', NaN);
                end
                if isvalid(fallMarkerZ)
                    set(fallMarkerZ, 'XData', NaN, 'YData', NaN);
                end


                % Update fall markers
                if fall_detected
                    last_fall_index = find(falls, 1, 'last');
                    if isvalid(fallMarkerX)
                        set(fallMarkerX, 'XData', app.accelTime(last_fall_index), 'YData', app.accelXData(last_fall_index));
                    end
                    if isvalid(fallMarkerY)
                        set(fallMarkerY, 'XData', app.accelTime(last_fall_index), 'YData', app.accelYData(last_fall_index));
                    end
                    if isvalid(fallMarkerZ)
                        set(fallMarkerZ, 'XData', app.accelTime(last_fall_index), 'YData', app.accelZData(last_fall_index));
                    end
                end

                % Highlight falls
                % hold(app.UIAxesAccX, 'on');
                % plot(app.UIAxesAccX, app.accelTime(falls), app.accelXData(falls), 'ro');
                % hold(app.UIAxesAccX, 'off');
                %
                % hold(app.UIAxesAccY, 'on');
                % plot(app.UIAxesAccY, app.accelTime(falls), app.accelYData(falls), 'ro');
                % hold(app.UIAxesAccY, 'off');
                %
                % hold(app.UIAxesAccZ, 'on');
                % plot(app.UIAxesAccZ, app.accelTime(falls), app.accelZData(falls), 'ro');
                % hold(app.UIAxesAccZ, 'off');
                %
                % drawnow;

                % Pause briefly to allow GUI updates


                % disp(app.accelXData(falls(1,1)));
                % disp('next')
                % disp('x'+ app.accelXData(falls));
                % disp('x'+ app.accelXData(falls(end)));
                % disp('y'+app.accelYData(falls));
                % disp('z'+app.accelZData(falls));


                % Highlight falls without accumulating markers
                cla(app.UIAxesAccX);
                cla(app.UIAxesAccY);
                cla(app.UIAxesAccZ);

                plot(app.UIAxesAccX, app.accelTime, app.accelXData);
                hold(app.UIAxesAccX, 'on');
                plot(app.UIAxesAccX, app.accelTime(falls), app.accelXData(falls), 'ro');
                hold(app.UIAxesAccX, 'off');

                plot(app.UIAxesAccY, app.accelTime, app.accelYData);
                hold(app.UIAxesAccY, 'on');
                plot(app.UIAxesAccY, app.accelTime(falls), app.accelYData(falls), 'ro');
                hold(app.UIAxesAccY, 'off');

                plot(app.UIAxesAccZ, app.accelTime, app.accelZData);
                hold(app.UIAxesAccZ, 'on');
                plot(app.UIAxesAccZ, app.accelTime(falls), app.accelZData(falls), 'ro');
                hold(app.UIAxesAccZ, 'off');

                drawnow;

                % Pause briefly to allow GUI updates
                pause(0.1);
            end

        end
        %error function

        function errorFunction(app,e)
            disp(e);
            app.ErrorlabelLabel.Text = ['ERROR: ', e];
            app.ErrorlabelLabel.Visible = 'on';
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectionStatusLamp.Color='red';
            app.ErrorlabelLabel.Visible = 'off';
            app.figAccelX = plot(app.UIAxesAccX, [0], [0]);
            app.figAccelY = plot(app.UIAxesAccY, [0], [0]);
            app.figAccelZ = plot(app.UIAxesAccZ, [0], [0]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {848, 848};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {342, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: EnterIPAddressEditField
        function EnterIPAddressEditFieldValueChanged(app, event)

            app.ipAdress = ['http://' app.EnterIPAddressEditField.Value '/'];
            try
                if(app.ipAdress == 'http://192.168.1.119:8080/')
                    disp(app.ipAdress)
                    app.ConnectionStatusLamp.Color='green';
                    app.DisconnectedLabel.FontColor = [0.01,0.53,0.01];
                    app.DisconnectedLabel.Text= 'Connected'

                    app.ErrorlabelLabel.Visible = 'off';

                end
            catch e
                app.DisconnectedLabel.Text= 'Disconnected'
                app.DisconnectedLabel.FontColor = 'red';
                app.errorFunction('Invalid IP Address');

                return;
            end


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %             app.accelXData=[];
            %             app.accelYData=[];
            %             app.accelZData=[];
            %             app.accelTime=[];
            try
                app.record = 1;
                [~] = webread([app.ipAdress app.startSting], app.options);
                app.pollData();
            catch e
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                app.record = 0;
                [~] = webread([app.ipAdress app.stopSting], app.options);
                set(app.figAccelX, 'XData', app.accelTime, 'YData', app.accelXData);
                set(app.figAccelY, 'XData', app.accelTime, 'YData', app.accelYData);
                set(app.figAccelZ, 'XData', app.accelTime, 'YData', app.accelZData);
            catch e

            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.record = 0;
            [~] = webread([app.ipAdress app.clearString], app.options);

            % Clear the plots
            cla(app.UIAxesAccX);
            cla(app.UIAxesAccY);
            cla(app.UIAxesAccZ);

            % Reinitialize the plots
            app.figAccelX = plot(app.UIAxesAccX, [0], [0]);
            app.figAccelY = plot(app.UIAxesAccY, [0], [0]);
            app.figAccelZ = plot(app.UIAxesAccZ, [0], [0]);

            set(app.figAccelX, 'XData', [], 'YData', []);
            set(app.figAccelY, 'XData', [], 'YData', []);
            set(app.figAccelZ, 'XData', [], 'YData', []);

            % Clear the data
            app.accelXData = [];
            app.accelYData = [];
            app.accelZData = [];
            app.accelTime = [];

            % Reset fall count
            app.fallCount = 0;
            app.Label.Text = num2str(app.fallCount);
            app.NormalLabel.Text = 'Null';

        end

        % Callback function
        function EnterThresholdEditFieldValueChanged(app, event)
            app.thresholdValue = app.EnterThresholdEditField.Value;

        end

        % Value changed function: ThresholdDropDown
        function ThresholdDropDownValueChanged(app, event)
            value = app.ThresholdDropDown.Value;
            if(strcmp(value, "Adult"))
                app.thresholdValue = 10;
            elseif (strcmp(value, "Young"))
                app.thresholdValue = 5;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearAllButton = uibutton(app.LeftPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.httpIP_AdresseLabel = uilabel(app.LeftPanel);
            app.httpIP_AdresseLabel.Text = 'Enter IP Address';
            app.EnterIPAddressEditField = uieditfield(app.LeftPanel, 'text');
            app.EnterIPAddressEditField.ValueChangedFcn = createCallbackFcn(app, @EnterIPAddressEditFieldValueChanged, true);
            app.eg19216817621Label = uilabel(app.LeftPanel);
            app.eg19216817621Label.Text = 'e.g.192.168.176.21';
            app.ConnectionStatusLampLabel = uilabel(app.LeftPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.LeftPanel);
            app.DisconnectedLabel = uilabel(app.LeftPanel);
            app.DisconnectedLabel.Text = 'Disconnected';
            app.ThresholdDropDownLabel = uilabel(app.LeftPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.LeftPanel);
            app.ThresholdDropDown.Items = {'Adult', 'Young'};
            app.ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdDropDownValueChanged, true);
            app.ThresholdDropDown.Value = 'Adult';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '0';
            app.TimeLabel = uilabel(app.LeftPanel);
            app.TimeLabel.Text = 'Time';
            app.ErrorlabelLabel = uilabel(app.LeftPanel);
            app.ErrorlabelLabel.Text = 'Error label';
            app.YourpathienthasfaleenLabel = uilabel(app.LeftPanel);
            app.YourpathienthasfaleenLabel.Text = 'Your pathient has faleen';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesAccZ = uiaxes(app.RightPanel);
            title(app.UIAxesAccZ, 'Acceleration Z')
            xlabel(app.UIAxesAccZ, 't in S')
            ylabel(app.UIAxesAccZ, 'a_z in m/s^2')
            app.UIAxesAccY = uiaxes(app.RightPanel);
            title(app.UIAxesAccY, 'Acceleration Y')
            xlabel(app.UIAxesAccY, 't in S')
            ylabel(app.UIAxesAccY, 'a_y in m/s^2')
            app.UIAxesAccX = uiaxes(app.RightPanel);
            title(app.UIAxesAccX, 'Acceleration X')
            xlabel(app.UIAxesAccX, 't in S')
            ylabel(app.UIAxesAccX, 'a_x in m/s^2')
            app.NormalLabel = uilabel(app.RightPanel);
            app.NormalLabel.Text = 'Normal';
            app.FallStatusLabel = uilabel(app.RightPanel);
            app.FallStatusLabel.Text = 'Fall Status';
            app.LastFallTimeLabel = uilabel(app.RightPanel);
            app.LastFallTimeLabel.Text = 'Last Fall Time';
            app.UnknownLabel = uilabel(app.RightPanel);
            app.UnknownLabel.Text = 'Unknown';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = real_time_fall_detection_by_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end